classdef nCORTEx_console < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        ConnectButton            slrealtime.ui.control.ConnectButton
        LoggingLabel             matlab.ui.control.Label
        DataStreamingLabel       matlab.ui.control.Label
        PiezoPanel               matlab.ui.container.Panel
        ModelDropDown_4          matlab.ui.control.DropDown
        ModelDropDown_4Label     matlab.ui.control.Label
        PhotometryPanel          matlab.ui.container.Panel
        ModelDropDown_3          matlab.ui.control.DropDown
        ModelDropDown_3Label     matlab.ui.control.Label
        NeuropixelsPanel         matlab.ui.container.Panel
        ModelDropDown_2          matlab.ui.control.DropDown
        ModelDropDown_2Label     matlab.ui.control.Label
        PhaseDropDown            matlab.ui.control.DropDown
        PhaseDropDownLabel       matlab.ui.control.Label
        SelectSaveLocationLabel  matlab.ui.control.Label
        BrowseButton             matlab.ui.control.Button
        SaveLogButton            matlab.ui.control.Button
        PupillometryPanel        matlab.ui.container.Panel
        ModelDropDown            matlab.ui.control.DropDown
        ModelDropDownLabel       matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path=uigetdir;
            app.SelectSaveLocationLabel.Text=path;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            params = setSlParams(struct);
            tg = slrealtime;
            disp(strcat('Accessing', {' '},tg.TargetSettings.name,{'...'}));
            slbuild('nCORTEx');
            addpath('nCORTEx_slrealtime_rtw');
            addpath(genpath('nCORTEx_slrealtime_rtw'));
            load(tg,'nCORTEx.mldatx');
            start(tg);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            tg = slrealtime;            
            % save("/home/electro/Code_Repo/n-CORTEx/log.mat",'logsout')
            stop(tg);
            disp(strcat('Application',{' '},'Stopped'))
        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonPushed(app, event)
            savePath = app.SelectSaveLocationLabel.Text;
            disp(['saving log data to: ',savePath]);
            logData = evalin('base','logsout');
            save("/home/electro/Code_Repo/n-CORTEx/log.mat",'logData')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PupillometryPanel = uipanel(app.UIFigure);
            app.PupillometryPanel.Title = 'Pupillometry';
            app.ModelDropDownLabel = uilabel(app.PupillometryPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.PupillometryPanel);
            app.ModelDropDown.Value = 'Option 3';
            app.SaveLogButton = uibutton(app.UIFigure, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLogButton.Text = 'Save Log';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectSaveLocationLabel = uilabel(app.UIFigure);
            app.SelectSaveLocationLabel.Text = '<- Select Save Location';
            app.PhaseDropDownLabel = uilabel(app.UIFigure);
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.UIFigure);
            app.NeuropixelsPanel = uipanel(app.UIFigure);
            app.NeuropixelsPanel.Title = 'Neuropixels';
            app.ModelDropDown_2Label = uilabel(app.NeuropixelsPanel);
            app.ModelDropDown_2Label.Text = 'Model';
            app.ModelDropDown_2 = uidropdown(app.NeuropixelsPanel);
            app.ModelDropDown_2.Value = 'Option 3';
            app.PhotometryPanel = uipanel(app.UIFigure);
            app.PhotometryPanel.Title = 'Photometry';
            app.ModelDropDown_3Label = uilabel(app.PhotometryPanel);
            app.ModelDropDown_3Label.Text = 'Model';
            app.ModelDropDown_3 = uidropdown(app.PhotometryPanel);
            app.ModelDropDown_3.Value = 'Option 3';
            app.PiezoPanel = uipanel(app.UIFigure);
            app.PiezoPanel.Title = 'Piezo';
            app.ModelDropDown_4Label = uilabel(app.PiezoPanel);
            app.ModelDropDown_4Label.Text = 'Model';
            app.ModelDropDown_4 = uidropdown(app.PiezoPanel);
            app.ModelDropDown_4.Value = 'Option 3';
            app.DataStreamingLabel = uilabel(app.UIFigure);
            app.DataStreamingLabel.Text = 'Data Streaming';
            app.LoggingLabel = uilabel(app.UIFigure);
            app.LoggingLabel.Text = 'Logging';
            app.ConnectButton = slrealtime.ui.control.ConnectButton(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'slrtRunIcon.png';
            app.StartButton.Text = '';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'slrtStopIcon.png';
            app.StopButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nCORTEx_console

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end